classdef imrotateGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SelectROIButton          matlab.ui.control.Button
        RotateAngleSpinner       matlab.ui.control.Spinner
        RotateAngleSpinnerLabel  matlab.ui.control.Label
        ImageAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgInitial; % Raw image
    end
    
    properties (Access = public)
        rotateAngle; 
        selectedRect;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img, highlow)
            axis(app.ImageAxes, 'image');
            imshow(imadjust(img),'Parent',app.ImageAxes);
            app.imgInitial = img;
        end

        % Value changed function: RotateAngleSpinner
        function RotateAngleSpinnerValueChanged(app, event)
            app.app.rotateAngle = app.app.rotateAngleSpinner.Value;
            rotatedImage = imrotate(app.imgInitial, app.rotateAngle,'bicubic','crop');
            imshow(imadjust(rotatedImage),'Parent',app.ImageAxes);
%             assignin('base', 'app.rotateAngle', app.rotateAngle);
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            app.selectedRect = images.internal.crop.interactiveCrop(app.ImageAxes);
%             assignin('base', 'ROI', app.selectedRect);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.RotateAngleSpinnerLabel = uilabel(app.UIFigure);
            app.RotateAngleSpinnerLabel.Text = 'Rotate Angle';
            app.RotateAngleSpinner = uispinner(app.UIFigure);
            app.RotateAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @RotateAngleSpinnerValueChanged, true);
            app.SelectROIButton = uibutton(app.UIFigure, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imrotateGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end