classdef drawroiGUItest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RotateAngleSpinner       matlab.ui.control.Spinner
        RotateAngleSpinnerLabel  matlab.ui.control.Label
        SelectROIButton          matlab.ui.control.Button
    end


    properties (Access = public)
        ImageAxesParent
        imgProcessed
        roi
        rotateAngle
        roiPosition
        nRow
        nCol
        nPanel
        channelOrFreqdiv
        rowLabel
        colLabel
        tempImg
    end

    methods (Access = private)
%% Create panels
        function createPanels(app, postInfo)
            % Retrieve screen size and initialize properties
            screenSize = get(0, 'ScreenSize');
            app.roiPosition = [];
            app.rotateAngle = 0;

            % Define layout parameters
            layoutParams = struct('left', 80, 'top', 40, 'intervalRight', 20, 'intervalBot', 20, ...
                'tempSpace', 70, 'rowLabelHeight', 90, 'colLabelWidth', 150);

            % Determine the number of rows and columns based on postInfo
            [app.nRow, app.nCol, app.channelOrFreqdiv] = getRowsAndColumns();
            app.nPanel = app.nCol * app.nRow;

            % Calculate panel size and window dimensions
            panelHeight = min((screenSize(4) - layoutParams.tempSpace - screenSize(4)/10) / app.nRow - layoutParams.intervalBot, 400);
            panelWidth = panelHeight;
            winWidth = layoutParams.left + (panelWidth + layoutParams.intervalRight) * app.nCol;
            winHeight = layoutParams.top + (panelHeight + layoutParams.intervalBot) * app.nRow + layoutParams.tempSpace;
            bot = winHeight - panelHeight - layoutParams.top; % first panel bot

            % Create panels, labels, and components in a single loop
            for iPanel = 1:app.nPanel
                [iRow, iCol] = ind2sub([app.nRow, app.nCol], iPanel);
                app.ImageAxesParent{iPanel} = uiaxes(app.UIFigure, 'Position', getPanelPosition(iRow, iCol));
                configureAxes(app.ImageAxesParent{iPanel});

                if iCol == 1
                    app.rowLabel{iRow} = uilabel(app.UIFigure, 'Text', ['Channel ' num2str(iRow)], ...
                        'Position', getRowLabelPosition(iRow), 'FontSize', 16);
                end

                if iRow == app.nRow
                    app.colLabel{iCol} = uilabel(app.UIFigure, 'Position', getColLabelPosition(iCol), ...
                        'FontSize', 16);
                end
            end

            % Position rotate angle spinner and label
            spinnerParams = struct('space', 15, 'spinnerToButton', 50, 'angleLabelWidth', 120, ...
                'angleLabelHeight', 26, 'spinnerWidth', 210, 'spinnerHeight', 38, ...
                'buttonHeight', 42, 'buttonBot', 25, 'buttonWidth', 180);
            positionRotateAngleComponents();

            % Position select ROI button
            positionSelectROIButton();

            % Position figure at the center of the screen
            app.UIFigure.Position = [(screenSize(3)-winWidth)/2, (screenSize(4)-winHeight)/2, winWidth, winHeight];


            % Helper function

            function [nRow, nCol, channelOrFreqdiv] = getRowsAndColumns()
                nChannel = length(postInfo.exportedChannelNo);
                nFreq = size(postInfo.frames);
                if nChannel == 1
                    if all(nFreq~=1)
                        error("Error: Too many dimensions to output.");
                    else
                        channelOrFreqdiv = false;
                        nRow = max(nFreq);
                    end
                else
                    if any(nFreq~=1)
                        error("Error: Too many dimensions to output.");
                    else
                        channelOrFreqdiv = true;
                        nRow = nChannel;
                    end
                end
                nTime = length(postInfo.frames{1});
                nCol = min(nTime, 3);
            end

            function panelPosition = getPanelPosition(iRow, iCol)
                panelPosition = [layoutParams.left + (panelWidth + layoutParams.intervalRight) * (iCol - 1), ...
                    bot - (panelHeight + layoutParams.intervalBot) * (iRow - 1), panelWidth, panelHeight];
            end

            function configureAxes(axesHandle)
                axesHandle.XTick = [];
                axesHandle.XTickLabelRotation = 0;
                axesHandle.XTickLabel = {''};
                axesHandle.YTick = [];
                axesHandle.YTickLabelRotation = 0;
                axesHandle.ZTickLabelRotation = 0;
            end

            function rowLabelPosition = getRowLabelPosition(iRow)
                rowLabelPosition = [5, bot + panelHeight/2 - (panelHeight+layoutParams.intervalBot)*(iRow-1), ...
                    layoutParams.left + 5, layoutParams.rowLabelHeight];
            end

            function colLabelPosition = getColLabelPosition(iCol)
                colLabelPosition = [layoutParams.left + panelWidth/2 - layoutParams.colLabelWidth/2 + (panelWidth + layoutParams.intervalRight) * (iCol - 1), ...
                    winHeight - 50, layoutParams.colLabelWidth, 50];
            end

            function positionRotateAngleComponents()
                app.RotateAngleSpinnerLabel.Position = [layoutParams.left + spinnerParams.space, ...
                    spinnerParams.buttonBot + spinnerParams.buttonHeight/2 - spinnerParams.angleLabelHeight/2, ...
                    spinnerParams.angleLabelWidth, spinnerParams.angleLabelHeight];
                app.RotateAngleSpinner.Position = [layoutParams.left + spinnerParams.space + spinnerParams.angleLabelWidth + spinnerParams.space, ...
                    spinnerParams.buttonBot + spinnerParams.buttonHeight/2 - spinnerParams.spinnerHeight/2, ...
                    spinnerParams.spinnerWidth, spinnerParams.spinnerHeight];
            end

            function positionSelectROIButton()
                if app.nCol == 1
                    angleComponentHalfWidth = (spinnerParams.angleLabelWidth + spinnerParams.spinnerWidth) / 2;
                    app.SelectROIButton.Position = [layoutParams.left + spinnerParams.space + angleComponentHalfWidth - spinnerParams.buttonWidth/2, ...
                        spinnerParams.buttonBot + spinnerParams.buttonHeight + 20, ...
                        spinnerParams.buttonWidth, spinnerParams.buttonHeight];
                else
                    app.SelectROIButton.Position = [layoutParams.left + spinnerParams.space + spinnerParams.angleLabelWidth + spinnerParams.spinnerWidth + spinnerParams.spinnerToButton, ...
                        spinnerParams.buttonBot, spinnerParams.buttonWidth, spinnerParams.buttonHeight];
                end
            end

        end

        %% show image
        function showImg(app, filename, postInfo, contrastMethod)

            % Determine contrast parameters based on the contrast method
            lowhigh = getContrastParameters();

            % Determine time indices based on postInfo
            time = getTimeIndices();

            % Read the image file and display images in panels
            f = Nd2Reader(filename);
            for iPanel = 1:app.nPanel
                [iRow, iCol] = ind2sub([app.nRow, app.nCol], iPanel);
                if app.channelOrFreqdiv
                    img = f.getimage(time(iCol));
                    app.imgProcessed{iPanel} = imadjust(img(:,:,iRow), lowhigh{1}(iRow, :));
                    app.colLabel{iCol}.Text = ['Frame =  ' num2str(time(iCol))];
                else
                    app.imgProcessed{iPanel} = imadjust(f.getimage(time(iRow, iCol)), lowhigh{iRow,:});
                    app.colLabel{iCol}.Text = ['Frame =  ' num2str(time(iRow, iCol))];
                end
                imshow(app.imgProcessed{iPanel}, 'Parent', app.ImageAxesParent{iPanel});
            end
            f.close();


            % helper function

            function lowhigh = getContrastParameters()
                switch contrastMethod
                    case 0
                        lowhigh = repmat([0 1], [app.nCol 1]);
                    case 1
                        lowhigh = postInfo.autoContrastPara;
                    case 2
                        lowhigh = postInfo.manualContrastPara;
                    case 3
                        lowhigh = postInfo.manualContrastPara;
                    otherwise
                        error('contrastMethod is unavailable.');
                end
            end

            function [time] = getTimeIndices()
                frames = postInfo.frames;
                if app.channelOrFreqdiv
                    idx = round(linspace(1, length(frames{1}), app.nCol));
                    time = frames{1}(idx);
                else
                    time = zeros(length(frames), app.nCol);
                    for iFreq = 1:length(frames)
                        idx = round(linspace(1, length(frames{iFreq}), app.nCol));
                        time(iFreq, :) = frames{iFreq}(idx);
                    end
                end
            end
        end

        function resetroi(app)
            if ~isempty(app.roi)
                cellfun(@delete, app.roi);
            end
        end

        function updateroi(app, iPanel)
            % disp('update1');
            app.roiPosition = app.roi{iPanel}.Position;
            app.updateroiall();
        end

        function updateroiall(app)
            % disp('updateall');
            for iPanel = 1:app.nPanel
                app.roi{iPanel}.Position = app.roiPosition;
            end
        end


    end

    methods (Access = public)

        function [rotateAngle, roiPosition, cropSize] = getroi(app)
            roiPosition = app.roiPosition;
            rotateAngle = app.rotateAngle;
            cropSize = getCropSize(app.imgProcessed{1});
            delete(app);  % important!! close window after grabbing data

            function cropSize = getCropSize(img)
                if isempty(roiPosition)
                    cropSize = size(img);
                else
                    cropSize = size(imcrop(img, roiPosition));
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filename, postInfo, contrastMethod)
            createPanels(app,postInfo);
            showImg(app, filename, postInfo, contrastMethod);
            uiwait(app.UIFigure);
        end

        % Value changed function: RotateAngleSpinner
        function RotateAngleSpinnerValueChanged(app, event)
            app.rotateAngle = app.RotateAngleSpinner.Value;
            for iPanel = 1:app.nPanel
                rotatedImage = imrotate(app.imgProcessed{iPanel}, app.rotateAngle,'bicubic','crop');
                app.resetroi();
                imshow(rotatedImage, 'Parent', app.ImageAxesParent{iPanel});
            end
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            app.resetroi();
            app.roi{1} = drawrectangle(app.ImageAxesParent{1});
            app.roiPosition = app.roi{1}.Position; % draw roi in 1st panel
            % draw other panel
            for iPanel = 2:app.nPanel
                app.roi{iPanel} = drawrectangle(app.ImageAxesParent{iPanel}, 'Position', app.roiPosition);
            end

            % move the rest panel when one panel moves
            for iPanel = 1:app.nPanel
                addlistener(app.roi{iPanel}, 'ROIMoved', @(~,~) app.updateroi(iPanel)); % check with events(roi)
            end
            uiwait(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiresume(app.UIFigure); % important!! grab data when click "close window"
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectROIButton = uibutton(app.UIFigure, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.RotateAngleSpinnerLabel = uilabel(app.UIFigure);
            app.RotateAngleSpinnerLabel.Text = 'Rotate Angle';
            app.RotateAngleSpinner = uispinner(app.UIFigure);
            app.RotateAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @RotateAngleSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drawroiGUItest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end