classdef imrotateGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SelectROIButton          matlab.ui.control.Button
        RotateAngleSpinner       matlab.ui.control.Spinner
        RotateAngleSpinnerLabel  matlab.ui.control.Label
        ImageAxes2               matlab.ui.control.UIAxes
        ImageAxes1               matlab.ui.control.UIAxes
    end


    properties (Access = public)
        im1; % Raw image
        im2;
        %         im3;
        rotateAngle;
        roi1;
        roiPosition;
        roi2;
    end

    methods (Access = private)

        function updateroi1(app)
            disp('update1');
            %             drawrectangle(app.ImageAxes1, 'DrawingArea',roi.Position);
            app.roiPosition = app.roi1.Position;
            app.updateroiall();
        end

        function updateroi2(app)
            disp('update2');
            app.roiPosition = app.roi2.Position;
            app.updateroiall();
        end

        function updateroiall(app)
            disp('updateall');
%                         delete(app.roi1);
%                         delete(app.roi2);
%                         app.roi1 = drawrectangle(app.ImageAxes1, 'Position',app.roiPosition);
%                         app.roi2 = drawrectangle(app.ImageAxes2, 'Position',app.roiPosition);
            app.roi1.Position = app.roiPosition;
            app.roi2.Position = app.roiPosition;
%                         addlistener(app.roi1, 'ROIMoved', @(~,~) app.updateroi1); % check with events(roi)
%                         addlistener(app.roi2, 'ROIMoved', @(~,~) app.updateroi2);
        end

        function resetroi(app)
            delete(app.roi1);
            delete(app.roi2);
        end

    end

    methods (Access = public)

        function roiPosition = getroi(app)
            roiPosition = app.roiPosition;
            delete(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img)
            axis(app.ImageAxes1, 'image');
            axis(app.ImageAxes2, 'image');
            imshow(imadjust(img),'Parent',app.ImageAxes1);
            imshow(imadjust(img),'Parent',app.ImageAxes2);
            app.im1 = img;
            app.im2 = img;
            uiwait(app.UIFigure);
        end

        % Value changed function: RotateAngleSpinner
        function RotateAngleSpinnerValueChanged(app, event)
            app.rotateAngle = app.RotateAngleSpinner.Value;
            rotatedImage1 = imrotate(app.im1, app.rotateAngle,'bicubic','crop');
            rotatedImage2 = imrotate(app.im2, app.rotateAngle,'bicubic','crop');
            app.resetroi();
            imshow(imadjust(rotatedImage1),'Parent',app.ImageAxes1);
            imshow(imadjust(rotatedImage2),'Parent',app.ImageAxes2);
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            if ~isempty(app.roi1)
                app.resetroi();
            end
            app.roi1 = drawrectangle(app.ImageAxes1);
            app.roiPosition = app.roi1.Position;
            app.roi2 = drawrectangle(app.ImageAxes2, 'Position',app.roi1.Position);
%             app.updateroiall();
            addlistener(app.roi1, 'ROIMoved', @(~,~) app.updateroi1); % check with events(roi)
            addlistener(app.roi2, 'ROIMoved', @(~,~) app.updateroi2);
            uiwait(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             uiresume(app.UIFigure);
% delete(app.roi1);
delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxes1 = uiaxes(app.UIFigure);
            app.ImageAxes1.XTick = [];
            app.ImageAxes1.XTickLabel = {'[ ]'};
            app.ImageAxes1.YTick = [];
            app.ImageAxes2 = uiaxes(app.UIFigure);
            app.ImageAxes2.XTick = [];
            app.ImageAxes2.XTickLabel = {'[ ]'};
            app.ImageAxes2.YTick = [];
            app.RotateAngleSpinnerLabel = uilabel(app.UIFigure);
            app.RotateAngleSpinnerLabel.Text = 'Rotate Angle';
            app.RotateAngleSpinner = uispinner(app.UIFigure);
            app.RotateAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @RotateAngleSpinnerValueChanged, true);
            app.SelectROIButton = uibutton(app.UIFigure, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imrotateGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end