classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.AutoResizeChildren = 'off';
            ax1 = subplot(1,2,1,'Parent',app.UIFigure);
            ax2 = subplot(1,2,2,'Parent',app.UIFigure);
            plot(ax1,[1 2 3 4])
            plot(ax2,[10 9 4 7])
            f = uifigure;
            d = uiprogressdlg(f,'Title','Image Exporter',...
                'Message','Exporting images...','Cancelable','on');
            drawnow
            for i = 1:100000
                d.Value = i/100000;
                d.Message = 'good';
            end
            close(d)
    close(f)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end