classdef v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ErrorTextArea                   matlab.ui.control.TextArea
        ErrorTextAreaLabel              matlab.ui.control.Label
        RequiredLabel                   matlab.ui.control.Label
        OptionalLabel                   matlab.ui.control.Label
        ExporttoVideoButton             matlab.ui.control.Button
        FrameRateEditField              matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel         matlab.ui.control.Label
        VideoFormatDropDown             matlab.ui.control.DropDown
        VideoFormatDropDownLabel        matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        Selectatleast1channelLabel      matlab.ui.control.Label
        ShortestSideEditField           matlab.ui.control.NumericEditField
        ShortestSideEditFieldLabel      matlab.ui.control.Label
        SliceEditField                  matlab.ui.control.EditField
        SliceEditFieldLabel             matlab.ui.control.Label
        SelectedChannelListBox          matlab.ui.control.ListBox
        SelectedChannelListBoxLabel     matlab.ui.control.Label
        CountEveryFramesEditField       matlab.ui.control.NumericEditField
        CountEveryFramesEditFieldLabel  matlab.ui.control.Label
        ChannelNumberEditField          matlab.ui.control.NumericEditField
        ChannelNumberEditFieldLabel     matlab.ui.control.Label
        ObjectivesEditField             matlab.ui.control.NumericEditField
        ObjectivesEditFieldLabel        matlab.ui.control.Label
        SavepathEditField               matlab.ui.control.EditField
        SavepathEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        UploadFilenameButton            matlab.ui.control.Button
        LoadingLabel                    matlab.ui.control.Label
        LoadParametersButton            matlab.ui.control.Button
        ManualContrastButton            matlab.ui.control.Button
        PreviewImageButton              matlab.ui.control.Button
        PreviewVideoButton              matlab.ui.control.Button
        TimeStampCheckBox               matlab.ui.control.CheckBox
        WithTextCheckBox                matlab.ui.control.CheckBox
        ScalebarCheckBox                matlab.ui.control.CheckBox
        ConcatenateCheckBox             matlab.ui.control.CheckBox
        AutoContrastCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        para;
        para_checkbox;
        post_info; % Information about original and compressed images.
        img_final;
        para_video;
    end
    
    methods (Access = private)
        
        function para = readpara(app)
            %Read parameters
            para.filename = app.FilenameEditField.Value;
            para.savepath = app.SavepathEditField.Value;
            para.obj = app.ObjectivesEditField.Value;
            para.output_every_frame = app.CountEveryFramesEditField.Value;
            para.split_channel_num = app.ChannelNumberEditField.Value;
            para.selected_channel = app.SelectedChannelListBox.Value;
            para.output_channel = zeros(1, numel(para.selected_channel));
            for i = 1:numel(para.selected_channel)
                para.output_channel(i) = str2double(para.selected_channel{i});
            end
            para.slice = eval(app.SliceEditField.Value);
            para.shortest_side = app.ShortestSideEditField.Value;
        end
        
        function checkpara(app)
            
            %Check parameters
            filename = app.para.filename;
            %             try
            imgInfo = ND2Info(filename);
            img_num = imgInfo.numImages;
            %             catch
            %                 error('Error: File is not supported or unavailble.');
            %             end
            
            savepath =  app.para.savepath;
            if ~isfolder(savepath)
                error('Error: Savepath is not a folder or does not exist.');
            end
            
            obj = app.para.obj;
            if obj <= 0
                error('Error: Objective must be > 0');
            end
            
            split_channel_num = app.para.split_channel_num;
            if mod(split_channel_num, 1) ~=0 || split_channel_num <=0 || split_channel_num > 4
                error('Error: Channel number must be integer and cannot handle channels > 4 or <= 0');
            end
            
            selected_channel = app.para.selected_channel;
            if isempty(selected_channel)
                error('Error: Must select at least one channel.');
            end
            
            shortest_side = app.para.shortest_side;
            if mod(shortest_side, 1)~= 0 || shortest_side <= 0 || shortest_side > 1024
                error('Error: Shortest side must be integer > 0 and < 1024');
            end
            
            slice = app.para.slice;
            if mod(slice, 1) ~= 0
                error('Error: Slice must be integer array.');
            end
            if ( ~isrow(slice) || size(slice, 2) > 2) && ~isempty(slice)
                error('Error: Slice must be a row vector of size 1 x 2');
            end
            if any(slice <= 0 & slice > img_num)
                error(['Error: Slice must be in the range of ' num2str(img_num)]);
            end
            if length(slice) == 2
                if slice(2) <= slice(1)
                    error('Error: Slice(2) must be larger than Slice(1).');
                end
            end
            
            output_every_frame = app.para.output_every_frame;
            if mod(output_every_frame, 1)~= 0 || output_every_frame <= 0 || output_every_frame > img_num
                error(['Error: Count per frame must be integer > 0 and < ' num2str(img_num)]);
            end
        end
        
        function post_info =  ND2analysis(app)
            
            %Read parameters
            filename = app.para.filename;
            obj = app.para.obj;
            output_every_frame = app.para.output_every_frame;
            split_channel_num = app.para.split_channel_num;
            output_channel = app.para.output_channel;
            slice = app.para.slice;
            shortest_side = app.para.shortest_side;
            
            interval = output_every_frame * split_channel_num;
            imgInfo = ND2Info(filename);
            img_num = imgInfo.numImages; % number of frames of movie
            
            if imgInfo.Experiment.parameters.durationMs == 0 % fast time lapse
                period_original = imgInfo.Experiment.parameters.periodDiff.avg/1000; % unit s
                fps_original = round(1/period_original);
            else % ND acquisition
                period_original = imgInfo.Experiment.parameters.periodMs/1000;
                fps_original = 1/period_original;
            end
            
            output_fps = fps_original / interval; % output fps
            period_final = period_original * interval;
            temp = ND2ReadSingle(filename, 1);
            min_size = min(size(temp));
            
            if min_size <= shortest_side
                scale = 1;
            else
                scale = shortest_side/min_size;
            end
            temp = imresize(temp, scale);
            
            %Ensure frame number of each channel are the same.
            exported_frame = cell(numel(output_channel), 1);
            compare_sample = cell(numel(output_channel), 1);
            for i = 1:numel(output_channel)
                if isempty(slice)
                    exported_frame{i} = output_channel(i):interval:img_num;
                    compare_sample{i} = ND2ReadSingle(filename, output_channel(i));
                elseif length(slice) == 2
                    exported_frame{i} = slice(1)+output_channel(i)-1:interval: slice(2);
                    compare_sample{i} = ND2ReadSingle(filename, slice(1)+output_channel(i)-1);
                elseif length(slice) == 1
                    exported_frame{i} = slice(1)+output_channel(i)-1;
                    compare_sample{i} = ND2ReadSingle(filename, slice(1)+output_channel(i)-1);
                end
            end
            
            minframe = min(cellfun(@numel, exported_frame));
            exported_frame = cellfun(@(x) x(1:minframe), exported_frame, 'UniformOutput', 0);
            exported_frame = uint16(cell2mat(exported_frame));
            
            %Sort the channel by intensity. (largest the first)
            intensity = cellfun(@(x) sum(x(:)), compare_sample);
            [~, i_sort] = sort(intensity, 'descend');
            exported_frame = exported_frame(i_sort, :);
            
            %Auto contrast parameters.
            lowhigh = autocontrastmovie(filename, exported_frame);
            
            %Info of compressed images.
            post_info.resize_scale = scale;  % scale of image resize.
            post_info.scale = 6.5/obj/scale;  % um/px
            post_info.original_fps = fps_original;  % original fps in the movie
            post_info.original_period = period_original;  % s
            post_info.final_fps = output_fps;   % final fps in the movie
            post_info.final_period = period_final; % s
            post_info.output_channel = output_channel;  % export channel
            post_info.compressed_img_size = size(temp);  % export movie size
            post_info.frames = exported_frame;
            post_info.lowhigh_auto = lowhigh;  % auto contrast parameter for imadjust
            
        end
        
        function [lowv, highv] = readlowhigh(~, im)
            lowv = []; % important to allocate here!
            highv = [];
            fig = figure;
            %             ax = uiaxes(imghandle);
            imh = imshow(im);
            ich = imcontrast(imh);
            set(ich, 'CloseRequestFcn',@myClose)
            function myClose(h,~)
                lowv = str2double(get(findobj(h,'tag','window min edit'), 'String'));
                highv = str2double(get(findobj(h,'tag','window max edit'), 'String'));
                delete(h)
            end
            waitfor(ich);
            close(fig);
        end
        
        function para = readcheckbox(app)
            para.auto_contrast = app.AutoContrastCheckBox.Value;
            para.cat_ornot = app.ConcatenateCheckBox.Value;
            para.add_scalebar = app.ScalebarCheckBox.Value;
            para.with_text = app.WithTextCheckBox.Value;
            para.add_timestamp = app.TimeStampCheckBox.Value;
        end
        
        function img_compressed = ND2compressor(app, frames)
            
            img_size = app.post_info.compressed_img_size;
            scale = app.post_info.resize_scale;
            filename= app.para.filename;
            %Initialization
            img_compressed = zeros([img_size size(frames, 2) size(frames, 1)], 'uint8');
            [FilePointer, ImagePointer, ImageReadOut] = ND2Open(filename);
            
            if app.para_checkbox.auto_contrast
                lowhigh = app.post_info.lowhigh_auto;
            else
                lowhigh = app.post_info.lowhigh_manual;
            end
            f = uifigure;
            d = uiprogressdlg(f,'Title','Image Compressor',...
                'Message','Compressing images...','Cancelable','on');
            id = 0;
            for istack = 1:size(frames, 1)
                if d.CancelRequested
                    break
                end
                icurrent = 0;
                for iimg = frames(istack, :)
                    if d.CancelRequested
                        break
                    end
                    icurrent = icurrent + 1;
                    img = ND2Read(FilePointer, ImagePointer, ImageReadOut, iimg);
                    img = imresize(img, scale); %Resize the image
                    %Contrast stretch image
                    img_compressed(:,:,icurrent, istack) = im2uint8(imadjust(img, lowhigh(istack, :)));
                    id = id +1;
                    d.Value = id/numel(frames);
                end
            end
            % Close the dialog box
            close(f);
            ND2Close(FilePointer)
            clear('FilePointer')
        end
        
        function img_cat = catimg(app)
            channel_num = numel(app.post_info.output_channel);
            
            if channel_num == 1
                img_cat = app.img_final;
                return;
            end
            
            img_cat = [];
            if channel_num > 1 && channel_num <= 4
                if channel_num == 2 || channel_num == 3
                    for i_stack = 1:channel_num
                        img_cat = [img_cat app.img_final(:,:,:, i_stack)];
                    end
                else
                    img_cat = [app.img_final(:,:,:, 1) app.img_final(:,:,:, 2); app.img_final(:,:,:, 3) app.img_final(:,:,:, 4)];
                end
            else
                error('Error: cannot handle the channel over 4.');
            end
        end
        
        function img_scalebar = labelscale(app)
            scale = app.post_info.scale;
            img_width = app.post_info.compressed_img_size(2);
            img_height = app.post_info.compressed_img_size(1);
            %             post_info_f = post_info;
            
            %Evaluate the position and width of a scale bar.
            right = round(0.028 * img_width); % dist of scalebar to right side
            bot = round(0.046 * img_height); % dist of scalebar to bot side
            bar_height = round(0.014 * img_height);
            bar_width = round(0.18 * img_width);
            real_bar_width = bar_width * scale;
            real_bar_width = round(real_bar_width, -floor(log10(real_bar_width)));
            bar_width = round(real_bar_width / scale);
            
            %Insert the scale bar.
            img_scalebar = app.img_final;
            img_scalebar(end-bot-bar_height:end-bot, end-right-bar_width:end-right, :,:) = 255;
            
            app.post_info.scalebar_um = real_bar_width; % um
        end
        
        function img_text = labeltext(app)
            [catimg_height, catimg_width] = size(app.img_final,[1 2]);
            img_width = app.post_info.compressed_img_size(2);
            img_height = app.post_info.compressed_img_size(1);
            bar_height = round(0.014 * img_height);
            bar_width = round(0.18 * img_width);
            right = round(0.028 * img_width); % dist of scalebar to right side
            bot = round(0.046 * img_height); % dist of scalebar to bot side
            img_text = app.img_final;
            text_str = [num2str(app.post_info.scalebar_um) ' μm'];
            position = [catimg_width-right-bar_width/2 catimg_height-bot-1.5*bar_height];
            for istack = 1:size(app.img_final,4)
                for i_img = 1:size(app.img_final,3)
                    RGB = insertText(app.img_final(:,:,i_img, istack),position,text_str,'FontSize',28,'BoxOpacity', 0, 'TextColor','white', 'AnchorPoint',"CenterBottom");
                    img_text(:,:,i_img, istack) = RGB(:,:,1);
                end
            end
        end
        
        function img_time = stamptime(app, frames)
            img_width = app.post_info.compressed_img_size(2);
            img_height = app.post_info.compressed_img_size(1);
            num_stack = size(app.img_final, 4);
            num_each_stack = size(app.img_final, 3);
            fps = app.post_info.original_fps;
            img_time = app.img_final;
            frames = double(frames);
            position = [round(0.007*img_width) round(0.007*img_height)];
            period = (max(frames, [], 'all')-1)/fps;
            interval = 1/fps; % s
            
            %Calculate the time interval (s)
            if interval < 1
                decimal_num = -floor(log10(interval));
                interval = round(interval, 3);  % s
            else
                decimal_num = 0;
                interval = round(interval,1);  % s
            end
            single_num = floor(log10(period))+1;
            f = uifigure;
            d = uiprogressdlg(f,'Title','Image Labeler',...
                'Message','Labeling images....','Cancelable','on');
            id = 0;
            for istack = 1:num_stack
                if d.CancelRequested
                    break
                end
                for i_img = 1:num_each_stack
                    if d.CancelRequested
                        break
                    end
                    time_stamp = (frames(istack, i_img)-1) * interval;
                    
                    %Evaluate time format
                    if period < 300
                        % if period is shorter than 5 min.  format x.xx s.
                        text_str = sprintf(['%' num2str(decimal_num + single_num + 1) '.' num2str(decimal_num) 'f s'],time_stamp);
                    elseif period < 3600
                        % if period is shorter than 1 h.  format xx:xx (m:s).
                        sec = floor(mod(time_stamp,60));
                        min = floor(time_stamp/60);
                        text_str = sprintf('%02d:%02d (m:s)',min, sec);
                    else
                        % if period is longer than 1 h.  format xx:xx:xx (h:m:s) or xx:xx (h:m).
                        hour = floor(time_stamp/3600);
                        remainer = mod(time_stamp,3600);
                        min = floor(remainer/60);
                        sec = floor(mod(remainer,60));
                        if mod(round(interval), 3600) == 0
                            text_str = sprintf('%02d h',hour);
                        elseif mod(round(interval), 60) == 0
                            text_str = sprintf('%02d:%02d (h:m)',hour, min);
                        else
                            text_str = sprintf('%02d:%02d:%02d (h:m:s)',hour, min, sec);
                        end
                    end
                    id = id +1 ;
                    RGB = insertText(img_time(:,:,i_img, istack),position,text_str,'Font','Lucida Console' ,'FontSize',28,'BoxOpacity', 0, 'TextColor','white');
                    
                    img_time(:,:,i_img, istack) = RGB(:,:,1);
                    d.Value = id/numel(frames);
                end
                % Close the dialog box
                close(f);
            end
            
        end
        
        function img2avi(app)
            video_format = app.VideoFormatDropDown.Value;
            video_fps = app.FrameRateEditField.Value;
            savepath = app.para.savepath;
            filename = app.para.filename;
            [~, file, ~] = fileparts(filename);
            savename = [savepath file ];
            
            num_img = size(app.img_final, 3);
            num_stack = size(app.img_final, 4);
            
            f = uifigure;
            d = uiprogressdlg(f,'Title','Image Exporter',...
                'Message','Exporting images...','Cancelable','on');
            id = 0;
            for istack = 1:num_stack
                if d.CancelRequested
                    break
                end
                savename1 = [savename '_' num2str(istack)];
                if size(app.img_final, 3) ~= 1
                    v = VideoWriter(savename1, video_format);
                    v.FrameRate = video_fps;
                    if strcmp(video_format, 'MPEG-4')
                        v.Quality = 75;
                    end
                    open(v);
                    for i_img = 1:num_img
                        if d.CancelRequested
                            break
                        end
                        writeVideo(v, app.img_final(:,:,i_img, istack));
                        id = id +1;
                        d.Value = id/num_img*num_stack;
                    end
                    close(v);
                else
                    imwrite(app.img_final(:,:,:, istack), [savename1 '.jpg']);
                end
            end
            close(f);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadFilenameButton
        function UploadFilenameButtonPushed(app, event)
            filterspec = {'*.nd2;*.tif'};
            [f, p] = uigetfile(filterspec);
            fname = [p f];
            if ischar(fname)
                app.FilenameEditField.Value = fname;
                app.SavepathEditField.Value = p;
            end
        end

        % Value changed function: ChannelNumberEditField
        function ChannelNumberEditFieldValueChanged(app, event)
            app.SelectedChannelListBox.Items = {};
            value = app.ChannelNumberEditField.Value;
            if mod(value, 1) ~=0 || value <=0 || value > 4
                errormsg = 'Error: Channel number must be integer and cannot handle channels > 4 or <= 0';
                app.LoadingLabel.Text = errormsg;
                app.LoadingLabel.Visible = true;
                app.LoadingLabel.FontColor = 'r';
                return;
            end
            for i = 1:value
                app.SelectedChannelListBox.Items{i} = num2str(i);
                app.SelectedChannelListBox.Value{i} = num2str(i);
            end
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: AutoContrastCheckBox
        function AutoContrastCheckBoxValueChanged(app, event)
            value = app.AutoContrastCheckBox.Value;
            if value
                app.ManualContrastButton.Enable = false;
            else
                app.ManualContrastButton.Enable = true;
            end
        end

        % Button pushed function: ManualContrastButton
        function ManualContrastButtonPushed(app, event)
            filename = app.FilenameEditField.Value;
            frames = app.post_info.frames;
            lowhigh = zeros(size(frames, 1), 2);
            if size(frames, 2) == 1
                idx_test = 1;
            else
                idx_test = [1 round(size(frames,2)/2) size(frames,2)];
            end
            for istack = 1:size(frames, 1)
                lowestv = 1; highestv = 0;
                for iimg = idx_test
                    im = im2double(ND2ReadSingle(filename, frames(istack, iimg)));
                    [lowv, highv]= readlowhigh(app, im);
                    lowestv = min(lowv, lowestv);
                    highestv = max(highv, highestv);
                end
                lowhigh(istack, :) = [lowestv highestv];
            end
            app.post_info.lowhigh_manual = lowhigh;
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            try
                app.para = readpara(app);
                checkpara(app);
                app.post_info =  ND2analysis(app);
                app.LoadingLabel.Text = 'Loaded Sucessfully!';
                app.LoadingLabel.Visible= true;
                app.LoadingLabel.FontColor = 'k';
            catch ME
                %                                             app.LoadingLabel.Text = 'Failed!';
                app.LoadingLabel.Text = ME.message;
                app.LoadingLabel.Visible = true;
                app.LoadingLabel.FontColor = 'r';
            end
            
        end

        % Value changing function: FilenameEditField
        function FilenameEditFieldValueChanging(app, event)
            app.LoadingLabel.Visible = false;
            filename = app.FilenameEditField.Value;
            [~, p] = uigetfile(filename); 
            app.SavepathEditField.Value = p;
        end

        % Value changed function: ObjectivesEditField
        function ObjectivesEditFieldValueChanged(app, event)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: CountEveryFramesEditField
        function CountEveryFramesEditFieldValueChanged(app, event)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: SelectedChannelListBox
        function SelectedChannelListBoxValueChanged(app, event)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changing function: SliceEditField
        function SliceEditFieldValueChanging(app, event)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: ShortestSideEditField
        function ShortestSideEditFieldValueChanged(app, event)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: SavepathEditField
        function SavepathEditFieldValueChanged(app, event)
            value = app.SavepathEditField.Value;
            if ~strcmp(value(end), '\')
                app.SavepathEditField.Value(end+1) = '\';
            end
        end

        % Button pushed function: PreviewImageButton
        function PreviewImageButtonPushed(app, event)
            app.para_checkbox = readcheckbox(app);
            
            try
                frames = app.post_info.frames;
                if numel(frames) > 1
                    frames = frames(:, [1 round(end/2) end]);
                end
                
                app.img_final = ND2compressor(app, frames);
                if app.para_checkbox.cat_ornot
                    app.img_final = catimg(app);
                end
                if app.para_checkbox.add_scalebar
                    app.img_final = labelscale(app);
                end
                if app.para_checkbox.with_text
                    app.img_final = labeltext(app);
                end
                if app.para_checkbox.add_timestamp
                    app.img_final = stamptime(app, frames);
                end
                
                % Preview the sample images.
                num_stack = size(app.img_final,4);
                num_each_stack = size(app.img_final,3);
                
                for iimg = 1:num_each_stack
                    for istack = 1:num_stack
                        subplot(num_each_stack, num_stack, istack+(iimg-1)*istack);
                        imshow(app.img_final(:,:,iimg, istack));
                        title(['#' num2str(frames(istack, iimg))]);
                    end
                end
                
                sgtitle('Preview Panel', 'Color','red', 'FontSize', 20);
            catch ME
                app.ErrorTextArea.Value{1} = [ME.identifier ME.message];
            end
            
        end

        % Button pushed function: PreviewVideoButton
        function PreviewVideoButtonPushed(app, event)
            app.para_checkbox = readcheckbox(app);
            
            frames = app.post_info.frames;
            app.img_final = ND2compressor(app, frames);
            if app.para_checkbox.cat_ornot
                app.img_final = catimg(app);
            end
            if app.para_checkbox.add_scalebar
                app.img_final = labelscale(app);
            end
            if app.para_checkbox.with_text
                app.img_final = labeltext(app);
            end
            if app.para_checkbox.add_timestamp
                app.img_final = stamptime(app, frames);
            end
            if size(app.img_final,3) == 1
                imshow(app.img_final);
            else
                implay(app.img_final, app.FrameRateEditField.Value);
            end
        end

        % Button pushed function: ExporttoVideoButton
        function ExporttoVideoButtonPushed(app, event)
            img2avi(app);
            f = uifigure;
            mess = "Finish converting the images.";
            title = "ND2 Converter";
            uialert(f, mess, title,'CloseFcn',@(h,e) close(f), 'Icon',"success");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AutoContrastCheckBox = uicheckbox(app.UIFigure);
            app.AutoContrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoContrastCheckBoxValueChanged, true);
            app.AutoContrastCheckBox.Text = 'Auto Contrast';
            app.AutoContrastCheckBox.Value = true;
            app.ConcatenateCheckBox = uicheckbox(app.UIFigure);
            app.ConcatenateCheckBox.Text = 'Concatenate';
            app.ConcatenateCheckBox.Value = true;
            app.ScalebarCheckBox = uicheckbox(app.UIFigure);
            app.ScalebarCheckBox.Text = 'Scalebar';
            app.ScalebarCheckBox.Value = true;
            app.WithTextCheckBox = uicheckbox(app.UIFigure);
            app.WithTextCheckBox.Text = 'With Text';
            app.TimeStampCheckBox = uicheckbox(app.UIFigure);
            app.TimeStampCheckBox.Text = 'Time Stamp';
            app.TimeStampCheckBox.Value = true;
            app.PreviewVideoButton = uibutton(app.UIFigure, 'push');
            app.PreviewVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewVideoButtonPushed, true);
            app.PreviewVideoButton.Text = 'Preview Video';
            app.PreviewImageButton = uibutton(app.UIFigure, 'push');
            app.PreviewImageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewImageButtonPushed, true);
            app.PreviewImageButton.Text = 'Preview Image';
            app.ManualContrastButton = uibutton(app.UIFigure, 'push');
            app.ManualContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ManualContrastButtonPushed, true);
            app.ManualContrastButton.Enable = 'off';
            app.ManualContrastButton.Text = 'Manual Contrast';
            app.LoadParametersButton = uibutton(app.UIFigure, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.LoadingLabel = uilabel(app.UIFigure);
            app.LoadingLabel.Text = 'Loading...';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.UploadFilenameButton = uibutton(app.ParametersPanel, 'push');
            app.UploadFilenameButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFilenameButtonPushed, true);
            app.UploadFilenameButton.Text = 'Upload Filename';
            app.FilenameEditFieldLabel = uilabel(app.ParametersPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.ParametersPanel, 'text');
            app.FilenameEditField.ValueChangingFcn = createCallbackFcn(app, @FilenameEditFieldValueChanging, true);
            app.FilenameEditField.Value = 'F:\Exp Data\multilayer_sizechange001.nd2';
            app.SavepathEditFieldLabel = uilabel(app.ParametersPanel);
            app.SavepathEditFieldLabel.Text = 'Savepath';
            app.SavepathEditField = uieditfield(app.ParametersPanel, 'text');
            app.SavepathEditField.ValueChangedFcn = createCallbackFcn(app, @SavepathEditFieldValueChanged, true);
            app.SavepathEditField.Value = 'F:\Exp Data\';
            app.ObjectivesEditFieldLabel = uilabel(app.ParametersPanel);
            app.ObjectivesEditFieldLabel.Text = 'Objectives';
            app.ObjectivesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ObjectivesEditField.ValueChangedFcn = createCallbackFcn(app, @ObjectivesEditFieldValueChanged, true);
            app.ObjectivesEditField.Value = 4;
            app.ChannelNumberEditFieldLabel = uilabel(app.ParametersPanel);
            app.ChannelNumberEditFieldLabel.Text = 'Channel Number';
            app.ChannelNumberEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ChannelNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelNumberEditFieldValueChanged, true);
            app.ChannelNumberEditField.Value = 1;
            app.CountEveryFramesEditFieldLabel = uilabel(app.ParametersPanel);
            app.CountEveryFramesEditFieldLabel.Text = 'Count Every Frames';
            app.CountEveryFramesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.CountEveryFramesEditField.ValueChangedFcn = createCallbackFcn(app, @CountEveryFramesEditFieldValueChanged, true);
            app.CountEveryFramesEditField.Value = 2;
            app.SelectedChannelListBoxLabel = uilabel(app.ParametersPanel);
            app.SelectedChannelListBoxLabel.Text = 'Selected Channel';
            app.SelectedChannelListBox = uilistbox(app.ParametersPanel);
            app.SelectedChannelListBox.Items = {'1'};
            app.SelectedChannelListBox.Multiselect = 'on';
            app.SelectedChannelListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedChannelListBoxValueChanged, true);
            app.SelectedChannelListBox.Value = {'1'};
            app.SliceEditFieldLabel = uilabel(app.ParametersPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SliceEditField = uieditfield(app.ParametersPanel, 'text');
            app.SliceEditField.ValueChangingFcn = createCallbackFcn(app, @SliceEditFieldValueChanging, true);
            app.SliceEditField.Value = '[]';
            app.ShortestSideEditFieldLabel = uilabel(app.ParametersPanel);
            app.ShortestSideEditFieldLabel.Text = 'Shortest Side';
            app.ShortestSideEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ShortestSideEditField.ValueChangedFcn = createCallbackFcn(app, @ShortestSideEditFieldValueChanged, true);
            app.ShortestSideEditField.Value = 720;
            app.Selectatleast1channelLabel = uilabel(app.ParametersPanel);
            app.Selectatleast1channelLabel.Visible = 'off';
            app.Selectatleast1channelLabel.Text = '(Select at least 1 channel)';
            app.VideoFormatDropDownLabel = uilabel(app.UIFigure);
            app.VideoFormatDropDownLabel.Text = 'Video Format';
            app.VideoFormatDropDown = uidropdown(app.UIFigure);
            app.VideoFormatDropDown.Items = {'MPEG-4', 'Uncompressed AVI'};
            app.VideoFormatDropDown.Value = 'MPEG-4';
            app.FrameRateEditFieldLabel = uilabel(app.UIFigure);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameRateEditField.Value = 20;
            app.ExporttoVideoButton = uibutton(app.UIFigure, 'push');
            app.ExporttoVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoVideoButtonPushed, true);
            app.ExporttoVideoButton.Text = 'Export to Video';
            app.OptionalLabel = uilabel(app.UIFigure);
            app.OptionalLabel.Text = '(Optional)';
            app.RequiredLabel = uilabel(app.UIFigure);
            app.RequiredLabel.Text = '(Required)';
            app.ErrorTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorTextAreaLabel.Text = 'Error';
            app.ErrorTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end